classdef BraidMeasureApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure             % UI Figure
        TabGroup             matlab.ui.container.TabGroup % Manual Upload, ...
        Tab1                 matlab.ui.container.Tab      % Manual Upload
        Panel                matlab.ui.container.Panel    % Upload Image
        UploadButton         matlab.ui.control.Button     % Upload Image
        Button2              matlab.ui.control.Button     % Measure Angle
        Panel2               matlab.ui.container.Panel    %  Display Options
        Panel3               matlab.ui.container.Panel    % Braid Angle
        Label                matlab.ui.control.Label     
        AngleDisplayTextArea matlab.ui.control.TextArea  
        StatusTextArea       matlab.ui.control.TextArea  
        Tab2                 matlab.ui.container.Tab      % Live Camera Col...
        StartCameraButton    matlab.ui.control.Button     % Button
    end


    properties (Access = private)
        uploadedBraidImage; % Description
        imageSizeX % Description
        imageSizeY % Description
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


        end

        % Button2 button pushed function
        function MeasureAngle(app)
%             %UNCOMMENT TO INCLUDE SPATIAL WINDOWING FUNCTION----------------------------------------------
%             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             %Spatially windows the image which removes discontinuities when using the
%             %discrete Fourier transform
%             
%             [ windowImage ] = spatialhanningwindow( app.uploadedBraidImage );
%             
%             %Brings the image into the frequency domain using the FFT algorithm
%             [ imageFrequencySpectrum ] = frequencytransform( windowImage );
%             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %UNCOMMENT TO NEGLECT SPATIAL WINDOWING FUNCTION-------------------
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            [ imageFrequencySpectrum ] = frequencytransform( app.uploadedBraidImage, [1500 1500] );
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %Scans for the primary high frequency data directions - these are the tow
            %directions
            [intensity, directionVector, angle, thetaRight, thetaLeft, indRight, indLeft, braidAngle, ~, ~, averageAngleDistribution, sortedCirclePoints] = braidanglescanBresenham(imageFrequencySpectrum);
            
            braidAngleResultString = strcat(sprintf('%0.2f',  braidAngle), char(176), char(177), sprintf('%0.2f', averageAngleDistribution), char(176));
            
            app.AngleDisplayTextArea.Value = braidAngleResultString;
            
%             %Write angle scan outputs to handle structure
%             handles.sortedCirclePoints = sortedCirclePoints;
%             handles.intensity = intensity;
%             handles.angle = angle;
%             handles.imageFrequencySpectrum = imageFrequencySpectrum;
%             handles.directionVector = directionVector;
%             handles.indRight = indRight;
%             handles.indLeft = indLeft;
            
            %Write angle scan outputs to workspace
            assignin('base', 'thetaRight', thetaRight)
            assignin('base', 'thetaLeft', thetaLeft)
            %assignin('base', 'windowImage', windowImage)
        end

        % UploadButton button pushed function
        function UploadButtonButtonPushed(app)
            [imageFileName, imagePathname, ~] = uigetfile({'*.jpg; *.tif; *.tiff; *.bmp; *.gif; *.jpeg',...
                'Image Files (*.jpg, *.tif, *.bmp, *.gif, *jpeg)'}); %Defining all image files
            if imageFileName == 0
                msgbox('Image was not selected')
            else
                app.StatusTextArea.Value = imageFileName;
                               
                %reading in selected image file as variable
                app.uploadedBraidImage = imread(strcat(imagePathname,imageFileName));
                
                %Size of image
                [sizeY, sizeX] = size(image);


            end
        end

        % StartCameraButton button pushed function
        function StartCameraButton_Callback(app)
            [file, path] = uiputfile({'.log'});
            logFilePath = strcat(path, file);
            logFileName = strtok(file, '.'); %separate file name from file extension
            realVideoBraid(logFilePath, logFileName);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Units = 'pixels';
            app.Tab1.Title = 'Manual Upload';
            app.Panel = uipanel(app.Tab1);
            app.Panel.Title = 'Upload Image';
            app.Panel.Units = 'pixels';
            app.UploadButton = uibutton(app.Panel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonButtonPushed);
            app.UploadButton.Text = 'Upload Image';
            app.Button2 = uibutton(app.Panel, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @MeasureAngle);
            app.Button2.Text = 'Measure Angle';
            app.Panel2 = uipanel(app.Panel);
            app.Panel2.Title = ' Display Options';
            app.Panel2.Units = 'pixels';
            app.Panel3 = uipanel(app.Panel);
            app.Panel3.Title = 'Braid Angle';
            app.Panel3.Units = 'pixels';
            app.Label = uilabel(app.Panel3);
            app.Label.Text = '';
            app.AngleDisplayTextArea = uitextarea(app.Panel3);
            app.AngleDisplayTextArea.Editable = 'off';
            app.StatusTextArea = uitextarea(app.Panel);
            app.StatusTextArea.Editable = 'off';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Live Camera Collection';
            app.StartCameraButton = uibutton(app.Tab2, 'push');
            app.StartCameraButton.ButtonPushedFcn = createCallbackFcn(app, @StartCameraButton_Callback);
        end
    end

    methods (Access = public)

        % Construct app
        function app = BraidMeasureApp()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end